classdef Spectra_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SPECTRAUIFigure                matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        DonorListBoxLabel              matlab.ui.control.Label
        DonorListBox                   matlab.ui.control.ListBox
        AcceptorListBoxLabel           matlab.ui.control.Label
        AcceptorListBox                matlab.ui.control.ListBox
        DonorExnmEditFieldLabel        matlab.ui.control.Label
        DonorExnmEditField             matlab.ui.control.NumericEditField
        AcceptorExnmEditFieldLabel     matlab.ui.control.Label
        AcceptorExnmEditField          matlab.ui.control.NumericEditField
        DonorEmCBWnmEditFieldLabel     matlab.ui.control.Label
        DonorEmCBWnmEditField          matlab.ui.control.EditField
        AcceptorEmCBWnmEditFieldLabel  matlab.ui.control.Label
        AcceptorEmCBWnmEditField       matlab.ui.control.EditField
    end


    properties (Access = private)
        Files % Description
        Spectra % Description
    end

    methods (Access = private)
        function h = GetHeader(app,fid)
            l = fgetl(fid);
            l = strsplit(l,',');
            if isempty(l{end}),l(end)=[];end
            h = l(2:end);
        end
        function data = GetData(app,fid)
            l = fgetl(fid);data=[];
            while ~isnumeric(l)
                d = strsplit(l,',','CollapseDelimiters',false);
                data(end+1,:)=str2double(d);
                l = fgetl(fid);
            end
            data(:,end)=[];
        end
        function names = GetNames(app,file)
            fid=fopen(file,'r');
            names = app.GetHeader(fid);
            fclose(fid);
        end
        function d = GetSpectrum(app,name)
            v = ismember(app.Spectra(:,1),name);
            file = app.Files(cell2mat(app.Spectra(v,2)));
            fid = fopen(fullfile(file.folder,file.name),'r');
            h = app.GetHeader(fid);
            d = app.GetData(fid);
            fclose(fid);
            d = d(:,[1,1+find(ismember(h,name))]);
            d(:,2)=d(:,2)./max(d(:,2));
        end
        function UpdateGUI(app)
            D = app.GetSpectrum(app.DonorListBox.Value);
            A = app.GetSpectrum(app.AcceptorListBox.Value);
            plot(app.UIAxes,D(:,1),D(:,2),A(:,1),A(:,2))
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function Load_Spectra(app)
            app.Files=dir('*\*.csv');
            app.Spectra={};
            for ct = 1:length(app.Files)
                n=GetNames(app,fullfile(app.Files(ct).folder,app.Files(ct).name));
                app.Spectra(end+1:end+length(n),1)=n;
                app.Spectra(end-length(n)+1:end,2)={ct};
            end
            app.DonorListBox.Items=app.Spectra(:,1);
            app.AcceptorListBox.Items=app.Spectra(:,1);
            app.UpdateGUI();
        end

        % Value changed function: DonorListBox
        function DonorListBoxValueChanged(app, event)
            app.UpdateGUI
        end

        % Value changed function: AcceptorListBox
        function AcceptorListBoxValueChanged(app, event)
            app.UpdateGUI
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SPECTRAUIFigure = uifigure;
            app.SPECTRAUIFigure.Name = 'SPECTRA';
            app.UIAxes = uiaxes(app.SPECTRAUIFigure);
            title(app.UIAxes, 'Spectra')
            xlabel(app.UIAxes, 'Lambda(nm)')
            ylabel(app.UIAxes, 'Normalized Intensity')
            app.DonorListBoxLabel = uilabel(app.SPECTRAUIFigure);
            app.DonorListBoxLabel.Text = 'Donor';
            app.DonorListBox = uilistbox(app.SPECTRAUIFigure);
            app.DonorListBox.ValueChangedFcn = createCallbackFcn(app, @DonorListBoxValueChanged, true);
            app.AcceptorListBoxLabel = uilabel(app.SPECTRAUIFigure);
            app.AcceptorListBoxLabel.Text = 'Acceptor';
            app.AcceptorListBox = uilistbox(app.SPECTRAUIFigure);
            app.AcceptorListBox.ValueChangedFcn = createCallbackFcn(app, @AcceptorListBoxValueChanged, true);
            app.DonorExnmEditFieldLabel = uilabel(app.SPECTRAUIFigure);
            app.DonorExnmEditFieldLabel.Text = 'Donor Ex (nm)';
            app.DonorExnmEditField = uieditfield(app.SPECTRAUIFigure, 'numeric');
            app.DonorExnmEditField.Value = 440;
            app.AcceptorExnmEditFieldLabel = uilabel(app.SPECTRAUIFigure);
            app.AcceptorExnmEditFieldLabel.Text = 'Acceptor Ex (nm)';
            app.AcceptorExnmEditField = uieditfield(app.SPECTRAUIFigure, 'numeric');
            app.AcceptorExnmEditField.Value = 500;
            app.DonorEmCBWnmEditFieldLabel = uilabel(app.SPECTRAUIFigure);
            app.DonorEmCBWnmEditFieldLabel.Text = 'Donor Em C/BW (nm)';
            app.DonorEmCBWnmEditField = uieditfield(app.SPECTRAUIFigure, 'text');
            app.DonorEmCBWnmEditField.Value = '440/30';
            app.AcceptorEmCBWnmEditFieldLabel = uilabel(app.SPECTRAUIFigure);
            app.AcceptorEmCBWnmEditFieldLabel.Text = 'Acceptor Em C/BW (nm)';
            app.AcceptorEmCBWnmEditField = uieditfield(app.SPECTRAUIFigure, 'text');
            app.AcceptorEmCBWnmEditField.Value = '550/30';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Spectra_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SPECTRAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @Load_Spectra)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SPECTRAUIFigure)
        end
    end
end